classdef chlamy_CDS_optimize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DNAbasesupstreamfromtargetCDSLabel  matlab.ui.control.Label
        Upstream40                    matlab.ui.control.EditField
        TargetaminoacidsequenceLabel  matlab.ui.control.Label
        Target_AA                     matlab.ui.control.EditField
        Go                            matlab.ui.control.Button
        ElapsedtimesecLabel           matlab.ui.control.Label
        Time                          matlab.ui.control.NumericEditField
        FEaroundATGLabel              matlab.ui.control.Label
        FE                            matlab.ui.control.NumericEditField
        PathLabel                     matlab.ui.control.Label
        Path                          matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        OutputfilenamewithoutextensionLabel  matlab.ui.control.Label
        Fname                         matlab.ui.control.EditField
        WeineretalThePlantJournal2018Label  matlab.ui.control.Label
        ChlamysequenceoptimizerLabel  matlab.ui.control.Label
        Requiredinput3fieldsLabel     matlab.ui.control.Label
        GCcontentLabel                matlab.ui.control.Label
        GC                            matlab.ui.control.NumericEditField
        OptimizedDNAsequenceEditFieldLabel  matlab.ui.control.Label
        Output_seq                    matlab.ui.control.EditField
        upload1                       matlab.ui.control.Button
        upload2                       matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Go
        function GoPushed(app, event)
            fourty_nt_upstream_atg = upper(app.Upstream40.Value);
            aa_seq = upper(app.Target_AA.Value);
            gene_title = 'optimized_gene';
            
            % check that everyhting is valid:
            % 1st input is valid DNA
            if is_valid_DNA_seq(fourty_nt_upstream_atg) == 0
                MSG = {'The first input must only consist of A/C/T/G';
                    '(both upper and lower case are acceptable)'}
                uialert(uifigure,MSG,'Invalid Input');
                return
            end
            
            % 1st input is at least 40 bp long
            if length(fourty_nt_upstream_atg) < 40
                MSG = {'The length of the DNA fragment in the first input field must be at least 40 nucleotides'; ...
                    '(if you give a longer sequence only the last 40 bases will be considered'};
                uialert(uifigure,MSG,'Invalid Input');
                return
            end
            
            % 2nd input is full
            if length(aa_seq) < 2
                MSG = {'The second input may not be left empty';
                    '(both upper and lower case are acceptable)'}
                uialert(uifigure,MSG,'Invalid Input');
                return
            end
            
            % 2nd input is valid
            if is_valid_AA_seq(aa_seq) == 0
                MSG = {'The second input must only consist of 1 letter amino acid letters';
                    '(both upper and lower case are acceptable)'}
                uialert(uifigure,MSG,'Invalid Input');
                return
            end
            
            % 3rd input is full, if not - fill it for the user
            if isempty(app.Fname.Value)
                app.Fname.Value = 'optimization_output'
            end
            output_file_name = [app.Fname.Value '.fa'];
            
            % set initial updates
            app.Lamp.Color = 'y';
            app.Path.Value = pwd;
            WB = waitbar(0,'Just started...');
            tic
            
            % organize input parameters
            load('ref_CDSs.mat')
            
            % tun optimization pipeline
            [optimized_dna_seq, best_mean_FE, gene_GC] = chlamy_optimize_coding_seq_IW(aa_seq, ...
                fourty_nt_upstream_atg, ref_CDSs, output_file_name, gene_title,WB);
            
            % wrap DNA string for clear visiblity
            %             C = 40;
            %             Final_op_DNA = repmat('_',1,C);
            %             for i = 1:C:length(optimized_dna_seq)
            %                 IDX = min([i+C-1, length(optimized_dna_seq)])
            %                 temp_row = optimized_dna_seq(i:IDX);
            %                 if length(temp_row) < C
            %                     Margin = C - length(temp_row);
            %                     temp_row = [temp_row, repmat(' ',1,Margin)];
            %                 end
            %                 Final_op_DNA = vertcat(Final_op_DNA, temp_row);
            %             end
            
            % finalize and give output
            app.Lamp.Color = 'b';
            app.Output_seq.Value = optimized_dna_seq;
            app.FE.Value = best_mean_FE;
            app.GC.Value = gene_GC;
            Elp_T = toc;
            app.Time.Value = Elp_T;
            % give waitbar indication
            waitbar(1,WB,'finished')
            % make sound :)
            load handel;
            sound(y,Fs);
            
        end

        % Button pushed function: upload1
        function upload1ButtonPushed(app, event)
            Seq = UI_get_seq_from_fasta;
            app.Upstream40.Value = upper(Seq);
        end

        % Button pushed function: upload2
        function upload2ButtonPushed(app, event)
            Seq = UI_get_seq_from_fasta;
            app.Target_AA.Value = upper(Seq);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DNAbasesupstreamfromtargetCDSLabel = uilabel(app.UIFigure);
            app.DNAbasesupstreamfromtargetCDSLabel.Text = '1. 40 DNA bases upstream from target CDS';
            app.Upstream40 = uieditfield(app.UIFigure, 'text');
            app.TargetaminoacidsequenceLabel = uilabel(app.UIFigure);
            app.TargetaminoacidsequenceLabel.Text = '2. Target amino acid sequence';
            app.Target_AA = uieditfield(app.UIFigure, 'text');
            app.Go = uibutton(app.UIFigure, 'push');
            app.Go.ButtonPushedFcn = createCallbackFcn(app, @GoPushed, true);
            app.Go.Text = 'Optimize my gene';
            app.ElapsedtimesecLabel = uilabel(app.UIFigure);
            app.ElapsedtimesecLabel.Text = 'Elapsed time (sec)';
            app.Time = uieditfield(app.UIFigure, 'numeric');
            app.FEaroundATGLabel = uilabel(app.UIFigure);
            app.FEaroundATGLabel.Text = 'FE around ATG';
            app.FE = uieditfield(app.UIFigure, 'numeric');
            app.PathLabel = uilabel(app.UIFigure);
            app.PathLabel.Text = 'Path';
            app.Path = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.OutputfilenamewithoutextensionLabel = uilabel(app.UIFigure);
            app.OutputfilenamewithoutextensionLabel.Text = '3. Output file name (without extension)';
            app.Fname = uieditfield(app.UIFigure, 'text');
            app.WeineretalThePlantJournal2018Label = uilabel(app.UIFigure);
            app.WeineretalThePlantJournal2018Label.Text = 'Weiner et al., The Plant Journal 2018';
            app.ChlamysequenceoptimizerLabel = uilabel(app.UIFigure);
            app.ChlamysequenceoptimizerLabel.Text = 'Chlamy sequence optimizer';
            app.Requiredinput3fieldsLabel = uilabel(app.UIFigure);
            app.Requiredinput3fieldsLabel.Text = 'Required input (3 fields):';
            app.GCcontentLabel = uilabel(app.UIFigure);
            app.GCcontentLabel.Text = 'GC content';
            app.GC = uieditfield(app.UIFigure, 'numeric');
            app.OptimizedDNAsequenceEditFieldLabel = uilabel(app.UIFigure);
            app.OptimizedDNAsequenceEditFieldLabel.Text = 'Optimized DNA sequence';
            app.Output_seq = uieditfield(app.UIFigure, 'text');
            app.upload1 = uibutton(app.UIFigure, 'push');
            app.upload1.ButtonPushedFcn = createCallbackFcn(app, @upload1ButtonPushed, true);
            app.upload1.Text = 'upload fasta';
            app.upload2 = uibutton(app.UIFigure, 'push');
            app.upload2.ButtonPushedFcn = createCallbackFcn(app, @upload2ButtonPushed, true);
            app.upload2.Text = 'upload fasta';
        end
    end

    methods (Access = public)

        % Construct app
        function app = chlamy_CDS_optimize

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end